classdef buildTowerGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        BuildtowerButton           matlab.ui.control.Button
        JenghisKhanLabel           matlab.ui.control.Label
        ConnectButton              matlab.ui.control.Button
        BuildSettingsPanel         matlab.ui.container.Panel
        XEditFieldLabel            matlab.ui.control.Label
        XEditField                 matlab.ui.control.NumericEditField
        mmLabel                    matlab.ui.control.Label
        mmLabel_2                  matlab.ui.control.Label
        YEditFieldLabel            matlab.ui.control.Label
        YEditField                 matlab.ui.control.NumericEditField
        DegreesLabel               matlab.ui.control.Label
        ThetaEditFieldLabel        matlab.ui.control.Label
        ThetaEditField             matlab.ui.control.NumericEditField
        LoadSideSwitchLabel        matlab.ui.control.Label
        LoadSideSwitch             matlab.ui.control.ToggleSwitch
        ConnectionStatusLampLabel  matlab.ui.control.Label
        ConnectionStatusLamp       matlab.ui.control.Lamp
        Tab2                       matlab.ui.container.Tab
    end


    properties (Access = public)
        serial % Description
    end
    
    methods (Access = private)
        function errors = sendRow(app, serial, data)
            [length,~] = size(data);
            errors = 0;
            for i = 1:length
                send = join(string(data(i, :)));
                disp("send: " + send);
                fprintf(serial, send);
                reply = strtrim(fscanf(serial));
                disp("recv: " + reply);
                
                values = sscanf(reply, '%f');
                for j = 1:6
                    a = values(j);
                    b = data(i,j);
                    e = abs(a-b);
                    if e > 1e-3
                        errors = errors + 1;
                        disp('ERROR of '+string(e)+': '+string(a)+' '+string(b))
                    end
                end
            end
        end
        
        function [xdata, ydata, zdata, thdata, gripdata] = sendTrajectory(app, serial, length, xdata, ydata, zdata, thdata, gripdata)
            %% send command N, indicating about to send N polys
            fprintf(serial, 'N');
            reply = strtrim(fscanf(serial));
            if ~strcmp(join(string(reply)), 'N')
                disp("Device did not reply correctly, expected 'N', got: "+join(string(reply)));
                fclose(serial);
                delete(serial);
                return;
            else
                disp('Device agreed to receive trajectories.')
            end
            %% send number of polys/row about to be sent
            fprintf(serial, string(length));
            reply = strtrim(fscanf(serial));
            if ~strcmp(join(string(reply)), string(length))
                disp('Device did not agree on length, will not send. '+join(string(reply)));
                fclose(serial);
                delete(serial);
                return;
            else
                disp('Device agreed on length of '+string(length)+'.')
            end
            %% send rows
            tic
            errors = 0;
            disp('Send x')
            errors = errors + app.sendRow(serial, xdata);
            disp('Send y')
            errors = errors + app.sendRow(serial, ydata);
            disp('Send z')
            errors = errors + app.sendRow(serial, zdata);
            disp('Send theta')
            errors = errors + app.sendRow(serial, thdata);
            disp('Send grip')
            errors = errors + app.sendRow(serial, gripdata);
            toc
            if errors > 0
                disp('*********************************************************')
                disp('WARNING - SERIAL COMMUNICATION ERRORS')
                disp('Number of coefficient mismatches: '+string(errors))
                disp('*********************************************************')
            end
        end
        
        function success = runControl(app, serial, type, chunk_i)
            % type is "PC" or "VC"
            disp('Run control on stored trajectories...')
            fprintf(serial, type);
            received = strtrim(fscanf(serial));
            if strcmp(received, type)
                if strcmp(type, "PC")
                    disp('Beginning position control.')
                elseif strcmp(type, "VC")
                    disp('Beginning velocity control.')
                end
            else
                disp('Device did not respond correctly: ' + join(string(received)))
            end
            
            % assume success, set false (0) if
            success = 1;
            
            global debugging
            if debugging
                % will read debug output until receives "DONE" from device
                if strcmp(type, "PC")
                    readPositionControlDebugging(serial)
                elseif strcmp(type, "VC")
                    readVelocityControlDebugging(serial)
                end
            else
                % wait for device to confirm position control completed planned trajectories
                while get(serial, 'BytesAvailable') == 0
                end
                
                received = strtrim(fscanf(serial));
                if strcmp(received, "DONE")
                    if nargin == 2 % no chunk_i input
                        disp('Path completed.')
                    elseif nargin == 3
                        disp('Chunk '+string(chunk_i)+' completed.')
                    end
                else
                    disp('Device did not respond correctly: ' + received)
                    disp('Stopping operation.')
                end
            end
        end
        
    end


    methods (Access = private)

        % Button pushed function: BuildtowerButton
        function BuildtowerButtonPushed(app, event)
%            xsent = 0;
%            ysent = 0;
%            zsent = 0;
%            thsent = 0;
%            gripsent = 0;
%            user = 4;
           
           [nchunks, chunks] = createMotionPlan(app.XEditField.Value,app.YEditField.Value,app.ThetaEditField.Value,app.LoadSideSwitch.Value);

           start_time = tic;
           
           for i = 1:nchunks
               data = chunks(:,:,:,i);
               xdata = data(:,:,1);
               ydata = data(:,:,2);
               zdata = data(:,:,3);
               thdata = data(:,:,4);
               gripdata = data(:,:,5);
               [length,~,~] = size(data);
               
               app.sendTrajectory(app.serial, length, xdata, ydata, zdata, thdata, gripdata);
               
               disp('Chunk '+string(i)+' of '+string(nchunks)+' sent.')
               success = app.runControl(app.serial, "PC", i);
               if ~success
                   return
               end
           end
           
           end_time = toc(start_time);
           disp('Duration of motion plan: '+string(end_time)+'s');

        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
        %% select serial port
        clear serial
        disp('Available COM ports:')
        ports = seriallist();
        options = string(zeros(1, length(ports)));
        for i = 1:length(ports)
            options(i) = string(i) +':' + ports(i);
        end
%         disp(join(options))
%         i = input('Select option:');
        i = 1;
        app.serial = serial(ports(i),'BAUD',57600);
        %% pause to make sure it's openes
        
        fopen(app.serial);
        pause(1);
        disp('Connected to '+ports(i))
        app.ConnectionStatusLamp.Color = [0 1 0]; % Turn Connection Lamp Green
        
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Tab';
            app.BuildtowerButton = uibutton(app.Tab, 'push');
            app.BuildtowerButton.ButtonPushedFcn = createCallbackFcn(app, @BuildtowerButtonPushed, true);
            app.BuildtowerButton.Text = 'Build tower';
            app.JenghisKhanLabel = uilabel(app.Tab);
            app.JenghisKhanLabel.Text = 'Jenghis Khan';
            app.ConnectButton = uibutton(app.Tab, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.BuildSettingsPanel = uipanel(app.Tab);
            app.BuildSettingsPanel.Title = 'Build Settings';
            app.XEditFieldLabel = uilabel(app.BuildSettingsPanel);
            app.XEditFieldLabel.Text = 'X: ';
            app.XEditField = uieditfield(app.BuildSettingsPanel, 'numeric');
            app.XEditField.Limits = [0 300];
            app.XEditField.Value = 200;
            app.mmLabel = uilabel(app.BuildSettingsPanel);
            app.mmLabel.Text = 'mm';
            app.mmLabel_2 = uilabel(app.BuildSettingsPanel);
            app.mmLabel_2.Text = 'mm';
            app.YEditFieldLabel = uilabel(app.BuildSettingsPanel);
            app.YEditFieldLabel.Text = 'Y: ';
            app.YEditField = uieditfield(app.BuildSettingsPanel, 'numeric');
            app.YEditField.Limits = [-300 300];
            app.YEditField.ValueDisplayFormat = '%.0f';
            app.YEditField.Value = -50;
            app.DegreesLabel = uilabel(app.BuildSettingsPanel);
            app.DegreesLabel.Text = 'Degrees';
            app.ThetaEditFieldLabel = uilabel(app.BuildSettingsPanel);
            app.ThetaEditFieldLabel.Text = 'Theta: ';
            app.ThetaEditField = uieditfield(app.BuildSettingsPanel, 'numeric');
            app.ThetaEditField.Limits = [-45 45];
            app.LoadSideSwitchLabel = uilabel(app.BuildSettingsPanel);
            app.LoadSideSwitchLabel.Text = 'Load Side';
            app.LoadSideSwitch = uiswitch(app.BuildSettingsPanel, 'toggle');
            app.LoadSideSwitch.Items = {'LEFT', 'RIGHT'};
            app.LoadSideSwitch.Value = 'RIGHT';
            app.ConnectionStatusLampLabel = uilabel(app.Tab);
            app.ConnectionStatusLampLabel.Text = 'Connection Status';
            app.ConnectionStatusLamp = uilamp(app.Tab);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
        end
    end

    methods (Access = public)

        % Construct app
        function app = buildTowerGUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end